classdef applicationLIPM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LinearInvertedPendulumModelAppUIFigure  matlab.ui.Figure
        PendulumHeightEditField       matlab.ui.control.NumericEditField
        PendulumHeightEditFieldLabel  matlab.ui.control.Label
        ResetAppButton                matlab.ui.control.Button
        SimulationTimeEditField       matlab.ui.control.NumericEditField
        SimulationTimeEditFieldLabel  matlab.ui.control.Label
        CalculateButton               matlab.ui.control.Button
        dyfEditField                  matlab.ui.control.NumericEditField
        dyfEditFieldLabel             matlab.ui.control.Label
        yfEditField                   matlab.ui.control.NumericEditField
        yfEditFieldLabel              matlab.ui.control.Label
        dxfEditField                  matlab.ui.control.NumericEditField
        dxfEditFieldLabel             matlab.ui.control.Label
        xfEditField                   matlab.ui.control.NumericEditField
        xfEditFieldLabel              matlab.ui.control.Label
        FinalConditionsLabel          matlab.ui.control.Label
        InitialConditionsLabel        matlab.ui.control.Label
        View3DButton                  matlab.ui.control.Button
        View2DButton                  matlab.ui.control.Button
        ClearPlotButton               matlab.ui.control.Button
        SimulateButton                matlab.ui.control.Button
        dy0EditField                  matlab.ui.control.NumericEditField
        dy0EditFieldLabel             matlab.ui.control.Label
        y0EditField                   matlab.ui.control.NumericEditField
        y0EditFieldLabel              matlab.ui.control.Label
        dx0EditField                  matlab.ui.control.NumericEditField
        dx0EditFieldLabel             matlab.ui.control.Label
        x0EditField                   matlab.ui.control.NumericEditField
        x0EditFieldLabel              matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Linear Inverted Pendulum Dynamics App
    % Copyright 2019 The MathWorks, Inc.
    
    properties (Access = public)
        Data % for storing dynamics data
    end
    
    properties (Access = private)
        UILine   % robot leg
        % UILine2  % robot trajectory
    end
    
    methods (Access = private)
        
        % Get discrete-time state-space model of linear inverted pendulum
        function [Ad, Bd, Cd, Dd] = getDiscStateSpace(~, g, zc, Ts)
            A = [0   1 0    0
                g/zc 0 0    0
                0    0 0    1
                0    0 g/zc 0]; 
            B = [0 0
                 1 0
                 0 0
                 0 1]; 
            C = [1 0 0 0
                 0 0 1 0]; 
            D = []; 
            
            lipm = ss(A,B,C,D); 
            lipmD = c2d(lipm, Ts); 
            
            Ad = lipmD.A; 
            Bd = lipmD.B;
            Cd = lipmD.C;
            Dd = lipmD.D; 
        end
        
        function setModelParameters(app, g, zc, Ts, tSingleSupport)
            % Get a discrete state-space model and assign it
            [Ad, Bd, Cd, Dd] = getDiscStateSpace(app, g, zc, Ts);    
            app.Data.Ad = Ad; 
            app.Data.Bd = Bd; 
            app.Data.Cd = Cd; 
            app.Data.Dd = Dd;      
            
            % Final body state and initial body state relationship
            app.Data.tSingleSupport = tSingleSupport; 
            Tc = sqrt(zc/g);  
            F = [cosh(tSingleSupport/Tc)     Tc*sinh(tSingleSupport/Tc);
                 sinh(tSingleSupport/Tc)/Tc  cosh(tSingleSupport/Tc)]; 
            F = [F zeros(2); 
                 zeros(2) F];
            app.Data.F = F; 
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % setting the parameters ================================
            g = 9.807; 
            zc = app.PendulumHeightEditField.Value; % initial height set from Design view  
            Ts = 0.005; % Sample time (sec)
            app.Data.g = g; 
            app.Data.zc = zc; 
            app.Data.zc0 = zc; 
            app.Data.Ts = Ts;
            app.Data.Ts0 = Ts; 
           
            % discrete state space model will be used to update plot 
            [Ad, Bd, Cd, Dd] = getDiscStateSpace(app, g, zc, Ts); 
            app.Data.Ad = Ad; 
            app.Data.Bd = Bd; 
            app.Data.Cd = Cd; 
            app.Data.Dd = Dd; 
            
            % get values from UI
            t_final = app.SimulationTimeEditField.Value; %sec
            steps = t_final / Ts; 
            x = zeros(steps, 1); 
            y = zeros(steps, 1); 
            z = zc*ones(steps, 1); % height is constant
            app.Data.tSingleSupport = t_final; 
            app.Data.tSingleSupport0 = t_final; 
            
            % initial values are set from Design View 
            x0 = app.x0EditField.Value; dx0 = app.dx0EditField.Value; 
            y0 = app.y0EditField.Value; dy0 = app.dy0EditField.Value; 
            state0 = [x0; dx0; y0; dy0]; 
            meas0  = Cd*state0; 
            u0     = [0; 0]; 
            x(1) = meas0(1);
            y(1) = meas0(2);
            app.Data.x = x; 
            app.Data.y = y; 
            app.Data.z = z;             
            app.Data.x0 = x0; 
            app.Data.y0 = y0; 
            app.Data.dx0 = dx0; 
            app.Data.dy0 = dy0; 
            app.Data.state0 = state0; 
            app.Data.meas0 = meas0; 
            app.Data.u0 = u0;
            app.Data.statef = state0; % just defining 
            
            % final body state and initial body state relationship ====
            Tsingle = app.Data.tSingleSupport; 
            Tc = sqrt(zc/g);  
            F = [cosh(Tsingle/Tc)     Tc*sinh(Tsingle/Tc);
                 sinh(Tsingle/Tc)/Tc  cosh(Tsingle/Tc)]; 
            F = [F zeros(2); 
                 zeros(2) F];
            app.Data.F = F; 

            % visualization initialization ================================
            hold(app.UIAxes, 'on'); 
            app.UILine  = plot3(app.UIAxes,[0,x(1)],[0,y(1)],[0,z(1)],... 
                '-o','MarkerIndices',2,'LineWidth',2,'MarkerSize',8);
            % app.UILine2 = plot3(app.UIAxes,x(1),y(1),z(1));
            hold(app.UIAxes, 'off'); 
            grid(app.UIAxes, 'on'); 
            view(app.UIAxes,3)
            app.UIAxes.XLim = [-zc/2 zc/2]; 
            app.UIAxes.YLim = [-zc/2 zc/2]; 
            app.UIAxes.ZLim = [0. 1.2*zc]; 
            legend(app.UIAxes, 'Virtual Leg of LIP', 'Location', 'northwest')
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            x = app.Data.x; 
            y = app.Data.y;
            z = app.Data.z; 
            Ad = app.Data.Ad; 
            Bd = app.Data.Bd;
            Cd = app.Data.Cd;
            Dd = app.Data.Dd; 
            state0 = app.Data.state0; 
            x0 = state0(1); 
            dx0 = state0(2);
            y0 = state0(3); 
            dy0 = state0(4); 
            u0 = app.Data.u0;
            zc = app.Data.zc; 
            
%             % reset plot before new simulation 
%             app.UILine2.XData = x0; 
%             app.UILine2.YData = y0; 
%             app.UILine2.ZData = zc;   
            
            % add new trajectory line 
            hold(app.UIAxes, 'on'); 
            TrajLine = plot3(app.UIAxes,x0,y0,zc);
            hold(app.UIAxes, 'off'); 
            
            t_final = app.Data.tSingleSupport; %s
            steps = round(t_final / app.Data.Ts) + 1;   
            for idx = 2:steps    
                state1 = Ad*state0 + Bd*u0;
                meas1 = Cd*state0 + Dd*u0; 
                state0 = state1; 
                x(idx) = meas1(1); 
                y(idx) = meas1(2); 
                z(idx) = zc; 
                app.UILine.XData = [0, x(idx)];
                app.UILine.YData = [0, y(idx)];
                app.UILine.ZData = [0, z(idx)];
%                 app.UILine2.XData(end+1) = x(idx); % add points to trajectory
%                 app.UILine2.YData(end+1) = y(idx); 
%                 app.UILine2.ZData(end+1) = z(idx);
                TrajLine.XData(end+1) = x(idx); % add points to trajectory
                TrajLine.YData(end+1) = y(idx); 
                TrajLine.ZData(end+1) = z(idx);
                pause(0.005); 
            end
            
            % populate final values 
            app.xfEditField.Value = state1(1); 
            app.dxfEditField.Value = state1(2); 
            app.yfEditField.Value = state1(3); 
            app.dyfEditField.Value = state1(4);           
            app.Data.statef = state1;
            
            % Update axes limits if needed
            app.UIAxes.XLim = [min(-zc/2,state1(1)), max(zc/2,state1(1))]; 
            app.UIAxes.YLim = [min(-zc/2,state1(3)), max(zc/2,state1(3))]; 
            
        end

        % Value changed function: x0EditField
        function x0EditFieldValueChanged(app, event)
            app.Data.state0(1) = app.x0EditField.Value;
        end

        % Value changed function: dx0EditField
        function dx0EditFieldValueChanged(app, event)
            app.Data.state0(2) = app.dx0EditField.Value;
        end

        % Value changed function: y0EditField
        function y0EditFieldValueChanged(app, event)
            app.Data.state0(3) = app.y0EditField.Value;
        end

        % Value changed function: dy0EditField
        function dy0EditFieldValueChanged(app, event)
            app.Data.state0(4) = app.dy0EditField.Value;
        end

        % Button pushed function: ClearPlotButton
        function ClearPlotButtonPushed(app, event)
            state0 = app.Data.state0; 
            x0 = state0(1); 
            y0 = state0(3); 
            zc = app.Data.zc; 
            
            cla(app.UIAxes); 
            
            app.UILine  = plot3(app.UIAxes,[0,x0],[0,y0],[0,zc],... 
                '-o','MarkerIndices',2,'LineWidth',2,'MarkerSize',8);
            legend(app.UIAxes, 'Virtual Leg of LIP', 'Location', 'northwest')
            app.UIAxes.XLim = [-zc/2 zc/2]; 
            app.UIAxes.YLim = [-zc/2 zc/2]; 
            app.UIAxes.ZLim = [0. 1.2*zc]; 
        end

        % Button pushed function: View2DButton
        function View2DButtonPushed(app, event)
            view(app.UIAxes, 2); 
        end

        % Button pushed function: View3DButton
        function View3DButtonPushed(app, event)
            view(app.UIAxes, 3); 
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
           
            state_final = app.Data.statef; 
            % final body state and initial body state relationship
            F = app.Data.F; 
            state_backtrack = F\state_final; 
            app.Data.state0 = state_backtrack; 
            
            % fill in the boxes 
            app.x0EditField.Value = state_backtrack(1); 
            app.dx0EditField.Value = state_backtrack(2); 
            app.y0EditField.Value = state_backtrack(3); 
            app.dy0EditField.Value = state_backtrack(4);          
        end

        % Value changed function: xfEditField
        function xfEditFieldValueChanged(app, event)
            app.Data.statef(1) = app.xfEditField.Value;          
        end

        % Value changed function: dxfEditField
        function dxfEditFieldValueChanged(app, event)
            app.Data.statef(2) = app.dxfEditField.Value;            
        end

        % Value changed function: yfEditField
        function yfEditFieldValueChanged(app, event)
            app.Data.statef(3) = app.yfEditField.Value;            
        end

        % Value changed function: dyfEditField
        function dyfEditFieldValueChanged(app, event)
            app.Data.statef(4) = app.dyfEditField.Value;           
        end

        % Value changed function: SimulationTimeEditField
        function SimulationTimeEditFieldValueChanged(app, event)
            app.Data.tSingleSupport = app.SimulationTimeEditField.Value;
            app.setModelParameters(app.Data.g, app.Data.zc, app.Data.Ts, app.Data.tSingleSupport); 
        end

        % Button pushed function: ResetAppButton
        function ResetAppButtonPushed(app, event)
            % reset initial state back to original 
            app.x0EditField.Value = app.Data.x0; 
            app.y0EditField.Value = app.Data.y0; 
            app.dx0EditField.Value = app.Data.dx0; 
            app.dy0EditField.Value = app.Data.dy0;            
            app.Data.state0(1) = app.x0EditField.Value;
            app.Data.state0(2) = app.dx0EditField.Value;
            app.Data.state0(3) = app.y0EditField.Value;
            app.Data.state0(4) = app.dy0EditField.Value;           

            % reset final value boxes 
            app.xfEditField.Value = 0; 
            app.yfEditField.Value = 0; 
            app.dxfEditField.Value = 0; 
            app.dyfEditField.Value = 0; 
            
            % reset simulation time 
            app.SimulationTimeEditField.Value = app.Data.tSingleSupport0; 
            app.Data.tSingleSupport = app.Data.tSingleSupport0; 
            
            % reset model parameters
            app.Data.zc = app.Data.zc0; 
            app.PendulumHeightEditField.Value = app.Data.zc0; 
            app.setModelParameters(app.Data.g, app.Data.zc0, app.Data.Ts0, app.Data.tSingleSupport); 
            
            % clear axes
            app.ClearPlotButtonPushed(); 
        end

        % Value changed function: PendulumHeightEditField
        function PendulumHeightEditFieldValueChanged(app, event)
            zc = app.PendulumHeightEditField.Value;
            app.Data.zc = zc;        
            state0 = app.Data.state0; 
            x0 = state0(1); 
            y0 = state0(3); 
            app.UILine.XData = [0, x0];
            app.UILine.YData = [0, y0];
            app.UILine.ZData = [0, zc];
                
            g = app.Data.g; 
            Ts = app.Data.Ts; 
            app.setModelParameters(g, zc, Ts, app.Data.tSingleSupport)
            app.UIAxes.XLim = [-zc/2 zc/2]; 
            app.UIAxes.YLim = [-zc/2 zc/2]; 
            app.UIAxes.ZLim = [0. 1.2*zc]; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LinearInvertedPendulumModelAppUIFigure = uifigure('Visible', 'off');
            app.LinearInvertedPendulumModelAppUIFigure.Name = 'Linear Inverted Pendulum Model App';
            app.UIAxes = uiaxes(app.LinearInvertedPendulumModelAppUIFigure);
            title(app.UIAxes, 'Visualization of LIPM Dynamics')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.x0EditFieldLabel = uilabel(app.LinearInvertedPendulumModelAppUIFigure);
            app.x0EditFieldLabel.Text = 'x0';
            app.x0EditField = uieditfield(app.LinearInvertedPendulumModelAppUIFigure, 'numeric');
            app.x0EditField.ValueChangedFcn = createCallbackFcn(app, @x0EditFieldValueChanged, true);
            app.x0EditField.Value = -0.18;
            app.dx0EditFieldLabel = uilabel(app.LinearInvertedPendulumModelAppUIFigure);
            app.dx0EditFieldLabel.Text = 'dx0';
            app.dx0EditField = uieditfield(app.LinearInvertedPendulumModelAppUIFigure, 'numeric');
            app.dx0EditField.ValueChangedFcn = createCallbackFcn(app, @dx0EditFieldValueChanged, true);
            app.dx0EditField.Value = 0.48;
            app.y0EditFieldLabel = uilabel(app.LinearInvertedPendulumModelAppUIFigure);
            app.y0EditFieldLabel.Text = 'y0';
            app.y0EditField = uieditfield(app.LinearInvertedPendulumModelAppUIFigure, 'numeric');
            app.y0EditField.ValueChangedFcn = createCallbackFcn(app, @y0EditFieldValueChanged, true);
            app.y0EditField.Value = 0.35;
            app.dy0EditFieldLabel = uilabel(app.LinearInvertedPendulumModelAppUIFigure);
            app.dy0EditFieldLabel.Text = 'dy0';
            app.dy0EditField = uieditfield(app.LinearInvertedPendulumModelAppUIFigure, 'numeric');
            app.dy0EditField.ValueChangedFcn = createCallbackFcn(app, @dy0EditFieldValueChanged, true);
            app.dy0EditField.Value = -1.18;
            app.SimulateButton = uibutton(app.LinearInvertedPendulumModelAppUIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.ClearPlotButton = uibutton(app.LinearInvertedPendulumModelAppUIFigure, 'push');
            app.ClearPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlotButton.Text = 'Clear Plot';
            app.View2DButton = uibutton(app.LinearInvertedPendulumModelAppUIFigure, 'push');
            app.View2DButton.ButtonPushedFcn = createCallbackFcn(app, @View2DButtonPushed, true);
            app.View2DButton.Text = 'View 2D';
            app.View3DButton = uibutton(app.LinearInvertedPendulumModelAppUIFigure, 'push');
            app.View3DButton.ButtonPushedFcn = createCallbackFcn(app, @View3DButtonPushed, true);
            app.View3DButton.Text = 'View 3D';
            app.InitialConditionsLabel = uilabel(app.LinearInvertedPendulumModelAppUIFigure);
            app.InitialConditionsLabel.Text = 'Initial Conditions';
            app.FinalConditionsLabel = uilabel(app.LinearInvertedPendulumModelAppUIFigure);
            app.FinalConditionsLabel.Text = 'Final Conditions';
            app.xfEditFieldLabel = uilabel(app.LinearInvertedPendulumModelAppUIFigure);
            app.xfEditFieldLabel.Text = 'xf';
            app.xfEditField = uieditfield(app.LinearInvertedPendulumModelAppUIFigure, 'numeric');
            app.xfEditField.ValueChangedFcn = createCallbackFcn(app, @xfEditFieldValueChanged, true);
            app.dxfEditFieldLabel = uilabel(app.LinearInvertedPendulumModelAppUIFigure);
            app.dxfEditFieldLabel.Text = 'dxf';
            app.dxfEditField = uieditfield(app.LinearInvertedPendulumModelAppUIFigure, 'numeric');
            app.dxfEditField.ValueChangedFcn = createCallbackFcn(app, @dxfEditFieldValueChanged, true);
            app.yfEditFieldLabel = uilabel(app.LinearInvertedPendulumModelAppUIFigure);
            app.yfEditFieldLabel.Text = 'yf';
            app.yfEditField = uieditfield(app.LinearInvertedPendulumModelAppUIFigure, 'numeric');
            app.yfEditField.ValueChangedFcn = createCallbackFcn(app, @yfEditFieldValueChanged, true);
            app.dyfEditFieldLabel = uilabel(app.LinearInvertedPendulumModelAppUIFigure);
            app.dyfEditFieldLabel.Text = 'dyf';
            app.dyfEditField = uieditfield(app.LinearInvertedPendulumModelAppUIFigure, 'numeric');
            app.dyfEditField.ValueChangedFcn = createCallbackFcn(app, @dyfEditFieldValueChanged, true);
            app.CalculateButton = uibutton(app.LinearInvertedPendulumModelAppUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Tooltip = {'Calculate the initial condition from the given final condition and Single Support Time (simulation time) '};
            app.CalculateButton.Text = 'Calculate';
            app.SimulationTimeEditFieldLabel = uilabel(app.LinearInvertedPendulumModelAppUIFigure);
            app.SimulationTimeEditFieldLabel.Text = 'Simulation Time';
            app.SimulationTimeEditField = uieditfield(app.LinearInvertedPendulumModelAppUIFigure, 'numeric');
            app.SimulationTimeEditField.ValueChangedFcn = createCallbackFcn(app, @SimulationTimeEditFieldValueChanged, true);
            app.SimulationTimeEditField.Value = 1;
            app.ResetAppButton = uibutton(app.LinearInvertedPendulumModelAppUIFigure, 'push');
            app.ResetAppButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAppButtonPushed, true);
            app.ResetAppButton.Text = 'Reset App';
            app.PendulumHeightEditFieldLabel = uilabel(app.LinearInvertedPendulumModelAppUIFigure);
            app.PendulumHeightEditFieldLabel.Text = 'Pendulum Height';
            app.PendulumHeightEditField = uieditfield(app.LinearInvertedPendulumModelAppUIFigure, 'numeric');
            app.PendulumHeightEditField.ValueChangedFcn = createCallbackFcn(app, @PendulumHeightEditFieldValueChanged, true);
            app.PendulumHeightEditField.Value = 1;
            % Show the figure after all components are created
            app.LinearInvertedPendulumModelAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = applicationLIPM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LinearInvertedPendulumModelAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LinearInvertedPendulumModelAppUIFigure)
        end
    end
end